classdef SigStudioFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SignalControlsPanel             matlab.ui.container.Panel
        StartFrequencyEditFieldLabel    matlab.ui.control.Label
        StartFrequencyField1            matlab.ui.control.NumericEditField
        StopFrequencyEditFieldLabel     matlab.ui.control.Label
        StopFrequencyField1             matlab.ui.control.NumericEditField
        StartFreqUnits1                 matlab.ui.control.DropDown
        StopFreqUnits1                  matlab.ui.control.DropDown
        Signal1DropDownLabel            matlab.ui.control.Label
        Signal1                         matlab.ui.control.DropDown
        Signal2DropDownLabel            matlab.ui.control.Label
        Signal2                         matlab.ui.control.DropDown
        StartFreqUnits2                 matlab.ui.control.DropDown
        StopFreqUnits2                  matlab.ui.control.DropDown
        CostasLengthDropDownLabel       matlab.ui.control.Label
        CostasLength1                   matlab.ui.control.DropDown
        BarkerLengthDropDownLabel       matlab.ui.control.Label
        BarkerLength1                   matlab.ui.control.DropDown
        BarkerFrequencyLabel            matlab.ui.control.Label
        BarkerFreq1                     matlab.ui.control.NumericEditField
        BarkerUnits1                    matlab.ui.control.DropDown
        CostasLengthDropDown_2Label     matlab.ui.control.Label
        CostasLength2                   matlab.ui.control.DropDown
        BarkerLengthDropDownLabel_2     matlab.ui.control.Label
        BarkerLength2                   matlab.ui.control.DropDown
        BarkerFrequencyLabel_2          matlab.ui.control.Label
        BarkerFreq2                     matlab.ui.control.NumericEditField
        BarkerUnits2                    matlab.ui.control.DropDown
        StartFrequencyEditFieldLabel_2  matlab.ui.control.Label
        StartFrequencyField2            matlab.ui.control.NumericEditField
        StopFrequencyEditFieldLabel_2   matlab.ui.control.Label
        StopFrequencyField2             matlab.ui.control.NumericEditField
        GolayLengthDropDownLabel        matlab.ui.control.Label
        GolayLength1                    matlab.ui.control.DropDown
        GolayLengthDropDownLabel_2      matlab.ui.control.Label
        GolayLength2                    matlab.ui.control.DropDown
        GolayFrequencyEditFieldLabel    matlab.ui.control.Label
        GolayFrequency1                 matlab.ui.control.NumericEditField
        GolayUnits1                     matlab.ui.control.DropDown
        GolayFrequencyEditFieldLabel_2  matlab.ui.control.Label
        GolayFrequency2                 matlab.ui.control.NumericEditField
        GolayUnits2                     matlab.ui.control.DropDown
        SimulationControlsPanel         matlab.ui.container.Panel
        SimulateButton                  matlab.ui.control.Button
        CrossRangeStopsSliderLabel      matlab.ui.control.Label
        CrossRangeStopsSlider           matlab.ui.control.Slider
        FilterOutputCheckBox            matlab.ui.control.CheckBox
        PlotMatchedFilteringCheckBox    matlab.ui.control.CheckBox
        PlotSignalsCheckBox             matlab.ui.control.CheckBox
        CarrierFrequencyEditFieldLabel  matlab.ui.control.Label
        CarrierFrequencyEditField       matlab.ui.control.NumericEditField
        CarrierUnits                    matlab.ui.control.DropDown
        PlotACFCheckBox                 matlab.ui.control.CheckBox
        execution                       matlab.ui.control.CheckBox
        SimulatingLabel                 matlab.ui.control.Label
        FilterThresholdSliderLabel      matlab.ui.control.Label
        FilterThreshold                 matlab.ui.control.Slider
        Warning                         matlab.ui.control.Label
        WindowWidthSliderLabel          matlab.ui.control.Label
        WindowWidth                     matlab.ui.control.Slider
        FreeSpaceLoss                   matlab.ui.control.CheckBox
        CarrierSignalCheckBox           matlab.ui.control.CheckBox
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Signal1
        function Signal1ValueChanged(app, event)
            value = app.Signal1.Value;
            if(strcmp(value,'Chirp (LFM)'))
                app.BarkerLength1.Visible = 'off';
                app.BarkerFreq1.Visible = 'off';
                app.CostasLength1.Visible = 'off';
                app.BarkerUnits1.Visible = 'off';
                app.StartFrequencyField1.Visible = 'on';
                app.StartFreqUnits1.Visible = 'on';
                app.StopFrequencyField1.Visible = 'on';
                app.StopFreqUnits1.Visible = 'on';
                app.GolayLength1.Visible = 'off';
                app.GolayUnits1.Visible = 'off';
                app.GolayFrequency1.Visible = 'off';
            elseif(strcmp(value,'Barker Code'))
                app.BarkerLength1.Visible = 'on';
                app.BarkerFreq1.Visible = 'on';
                app.BarkerUnits1.Visible = 'on';
                app.CostasLength1.Visible = 'off';
                app.StartFrequencyField1.Visible = 'off';
                app.StartFreqUnits1.Visible = 'off';
                app.StopFrequencyField1.Visible = 'off';
                app.StopFreqUnits1.Visible = 'off';
                app.GolayLength1.Visible = 'off';
                app.GolayUnits1.Visible = 'off';
                app.GolayFrequency1.Visible = 'off';
            elseif(strcmp(value,'Costas Code'))
                app.BarkerLength1.Visible = 'off';
                app.BarkerFreq1.Visible = 'off';
                app.BarkerUnits1.Visible = 'off';
                app.CostasLength1.Visible = 'on';
                app.StartFrequencyField1.Visible = 'on';
                app.StartFreqUnits1.Visible = 'on';
                app.StopFrequencyField1.Visible = 'on';
                app.StopFreqUnits1.Visible = 'on';
                app.GolayLength1.Visible = 'off';
                app.GolayUnits1.Visible = 'off';
                app.GolayFrequency1.Visible = 'off';
            elseif(strcmp(value,'Golay Code'))
                app.BarkerLength1.Visible = 'off';
                app.BarkerFreq1.Visible = 'off';
                app.BarkerUnits1.Visible = 'off';
                app.CostasLength1.Visible = 'off';
                app.StartFrequencyField1.Visible = 'off';
                app.StartFreqUnits1.Visible = 'off';
                app.StopFrequencyField1.Visible = 'off';
                app.StopFreqUnits1.Visible = 'off';
                app.GolayLength1.Visible = 'on';
                app.GolayUnits1.Visible = 'on';
                app.GolayFrequency1.Visible = 'on';
            end
        end

        % Value changed function: Signal2
        function Signal2ValueChanged(app, event)
            value = app.Signal2.Value;
            if(strcmp(value,'Chirp (LFM)'))
                app.BarkerLength2.Visible = 'off';
                app.BarkerFreq2.Visible = 'off';
                app.CostasLength2.Visible = 'off';
                app.BarkerUnits2.Visible = 'off';
                app.StartFrequencyField2.Visible = 'on';
                app.StartFreqUnits2.Visible = 'on';
                app.StopFrequencyField2.Visible = 'on';
                app.StopFreqUnits2.Visible = 'on';
                app.GolayLength2.Visible = 'off';
                app.GolayUnits2.Visible = 'off';
                app.GolayFrequency2.Visible = 'off';
            elseif(strcmp(value,'Barker Code'))
                app.BarkerLength2.Visible = 'on';
                app.BarkerFreq2.Visible = 'on';
                app.BarkerUnits2.Visible = 'on';
                app.CostasLength2.Visible = 'off';
                app.StartFrequencyField2.Visible = 'off';
                app.StartFreqUnits2.Visible = 'off';
                app.StopFrequencyField2.Visible = 'off';
                app.StopFreqUnits2.Visible = 'off';
                app.GolayLength2.Visible = 'off';
                app.GolayUnits2.Visible = 'off';
                app.GolayFrequency2.Visible = 'off';
            elseif(strcmp(value,'Costas Code'))
                app.BarkerLength2.Visible = 'off';
                app.BarkerFreq2.Visible = 'off';
                app.BarkerUnits2.Visible = 'off';
                app.CostasLength2.Visible = 'on';
                app.StartFrequencyField2.Visible = 'on';
                app.StartFreqUnits2.Visible = 'on';
                app.StopFrequencyField2.Visible = 'on';
                app.StopFreqUnits2.Visible = 'on';
                app.GolayLength2.Visible = 'off';
                app.GolayUnits2.Visible = 'off';
                app.GolayFrequency2.Visible = 'off';
            elseif(strcmp(value,'Golay Code'))
                app.BarkerLength2.Visible = 'off';
                app.BarkerFreq2.Visible = 'off';
                app.BarkerUnits2.Visible = 'off';
                app.CostasLength2.Visible = 'off';
                app.StartFrequencyField2.Visible = 'off';
                app.StartFreqUnits2.Visible = 'off';
                app.StopFrequencyField2.Visible = 'off';
                app.StopFreqUnits2.Visible = 'off';
                app.GolayLength2.Visible = 'on';
                app.GolayUnits2.Visible = 'on';
                app.GolayFrequency2.Visible = 'on';
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.execution.Value = 1; % used as a flag to continue the simulation
            app.SimulatingLabel.Visible = 'on';
        end

        % Value changed function: FilterOutputCheckBox
        function FilterOutputCheckBoxValueChanged(app, event)
            value = app.FilterOutputCheckBox.Value;
            if(value == 1) % filtering
                app.FilterThreshold.Visible = 'on';
                if(app.FilterThreshold.Value > 85)
                    app.Warning.Visible = 'on';
                end
            else
                app.FilterThreshold.Visible = 'off';
                app.Warning.Visible = 'off';
            end
        end

        % Value changed function: FilterThreshold
        function FilterThresholdValueChanged(app, event)
            value = app.FilterThreshold.Value;
            if(value > 85)
                app.Warning.Visible = 'on';
            else
                app.Warning.Visible = 'off';
            end
        end

        % Value changed function: CarrierSignalCheckBox
        function CarrierSignalCheckBoxValueChanged(app, event)
            value = app.CarrierSignalCheckBox.Value;
            if(app.CarrierSignalCheckBox.Value==1)
                app.CarrierFrequencyEditField.Visible = 'on';
                app.CarrierUnits.Visible = 'on';
            else
                app.CarrierFrequencyEditField.Visible = 'off';
                app.CarrierUnits.Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SignalControlsPanel = uipanel(app.UIFigure);
            app.SignalControlsPanel.Title = 'Signal Controls';
            app.StartFrequencyEditFieldLabel = uilabel(app.SignalControlsPanel);
            app.StartFrequencyEditFieldLabel.Text = 'Start Frequency';
            app.StartFrequencyField1 = uieditfield(app.SignalControlsPanel, 'numeric');
            app.StopFrequencyEditFieldLabel = uilabel(app.SignalControlsPanel);
            app.StopFrequencyEditFieldLabel.Text = 'Stop Frequency';
            app.StopFrequencyField1 = uieditfield(app.SignalControlsPanel, 'numeric');
            app.StartFreqUnits1 = uidropdown(app.SignalControlsPanel);
            app.StartFreqUnits1.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.StartFreqUnits1.Value = 'Hz';
            app.StopFreqUnits1 = uidropdown(app.SignalControlsPanel);
            app.StopFreqUnits1.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.StopFreqUnits1.Value = 'Hz';
            app.Signal1DropDownLabel = uilabel(app.SignalControlsPanel);
            app.Signal1DropDownLabel.Text = 'Signal 1';
            app.Signal1 = uidropdown(app.SignalControlsPanel);
            app.Signal1.Items = {'', 'Chirp (LFM)', 'Barker Code', 'Costas Code', 'Golay Code'};
            app.Signal1.ValueChangedFcn = createCallbackFcn(app, @Signal1ValueChanged, true);
            app.Signal1.Value = '';
            app.Signal2DropDownLabel = uilabel(app.SignalControlsPanel);
            app.Signal2DropDownLabel.Text = 'Signal 2';
            app.Signal2 = uidropdown(app.SignalControlsPanel);
            app.Signal2.Items = {'', 'Chirp (LFM)', 'Barker Code', 'Costas Code', 'Golay Code'};
            app.Signal2.ValueChangedFcn = createCallbackFcn(app, @Signal2ValueChanged, true);
            app.Signal2.Value = '';
            app.StartFreqUnits2 = uidropdown(app.SignalControlsPanel);
            app.StartFreqUnits2.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.StartFreqUnits2.Value = 'Hz';
            app.StopFreqUnits2 = uidropdown(app.SignalControlsPanel);
            app.StopFreqUnits2.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.StopFreqUnits2.Value = 'Hz';
            app.CostasLengthDropDownLabel = uilabel(app.SignalControlsPanel);
            app.CostasLengthDropDownLabel.Text = 'Costas Length';
            app.CostasLength1 = uidropdown(app.SignalControlsPanel);
            app.CostasLength1.Items = {'8', '10'};
            app.CostasLength1.Value = '8';
            app.BarkerLengthDropDownLabel = uilabel(app.SignalControlsPanel);
            app.BarkerLengthDropDownLabel.Text = 'Barker Length';
            app.BarkerLength1 = uidropdown(app.SignalControlsPanel);
            app.BarkerLength1.Items = {'2', '3', '4', '5', '7', '11', '13'};
            app.BarkerLength1.Value = '7';
            app.BarkerFrequencyLabel = uilabel(app.SignalControlsPanel);
            app.BarkerFrequencyLabel.Text = 'Barker Frequency';
            app.BarkerFreq1 = uieditfield(app.SignalControlsPanel, 'numeric');
            app.BarkerUnits1 = uidropdown(app.SignalControlsPanel);
            app.BarkerUnits1.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.BarkerUnits1.Value = 'Hz';
            app.CostasLengthDropDown_2Label = uilabel(app.SignalControlsPanel);
            app.CostasLengthDropDown_2Label.Text = 'Costas Length';
            app.CostasLength2 = uidropdown(app.SignalControlsPanel);
            app.CostasLength2.Items = {'8', '10'};
            app.CostasLength2.Value = '8';
            app.BarkerLengthDropDownLabel_2 = uilabel(app.SignalControlsPanel);
            app.BarkerLengthDropDownLabel_2.Text = 'Barker Length';
            app.BarkerLength2 = uidropdown(app.SignalControlsPanel);
            app.BarkerLength2.Items = {'2', '3', '4', '5', '7', '11', '13'};
            app.BarkerLength2.Value = '7';
            app.BarkerFrequencyLabel_2 = uilabel(app.SignalControlsPanel);
            app.BarkerFrequencyLabel_2.Text = 'Barker Frequency';
            app.BarkerFreq2 = uieditfield(app.SignalControlsPanel, 'numeric');
            app.BarkerUnits2 = uidropdown(app.SignalControlsPanel);
            app.BarkerUnits2.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.BarkerUnits2.Value = 'Hz';
            app.StartFrequencyEditFieldLabel_2 = uilabel(app.SignalControlsPanel);
            app.StartFrequencyEditFieldLabel_2.Text = 'Start Frequency';
            app.StartFrequencyField2 = uieditfield(app.SignalControlsPanel, 'numeric');
            app.StopFrequencyEditFieldLabel_2 = uilabel(app.SignalControlsPanel);
            app.StopFrequencyEditFieldLabel_2.Text = 'Stop Frequency';
            app.StopFrequencyField2 = uieditfield(app.SignalControlsPanel, 'numeric');
            app.GolayLengthDropDownLabel = uilabel(app.SignalControlsPanel);
            app.GolayLengthDropDownLabel.Text = 'Golay Length';
            app.GolayLength1 = uidropdown(app.SignalControlsPanel);
            app.GolayLength1.Items = {'32', '64', '128'};
            app.GolayLength1.Value = '32';
            app.GolayLengthDropDownLabel_2 = uilabel(app.SignalControlsPanel);
            app.GolayLengthDropDownLabel_2.Text = 'Golay Length';
            app.GolayLength2 = uidropdown(app.SignalControlsPanel);
            app.GolayLength2.Items = {'32', '64', '128'};
            app.GolayLength2.Value = '32';
            app.GolayFrequencyEditFieldLabel = uilabel(app.SignalControlsPanel);
            app.GolayFrequencyEditFieldLabel.Text = 'Golay Frequency';
            app.GolayFrequency1 = uieditfield(app.SignalControlsPanel, 'numeric');
            app.GolayUnits1 = uidropdown(app.SignalControlsPanel);
            app.GolayUnits1.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.GolayUnits1.Value = 'Hz';
            app.GolayFrequencyEditFieldLabel_2 = uilabel(app.SignalControlsPanel);
            app.GolayFrequencyEditFieldLabel_2.Text = 'Golay Frequency';
            app.GolayFrequency2 = uieditfield(app.SignalControlsPanel, 'numeric');
            app.GolayUnits2 = uidropdown(app.SignalControlsPanel);
            app.GolayUnits2.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.GolayUnits2.Value = 'Hz';
            app.SimulationControlsPanel = uipanel(app.UIFigure);
            app.SimulationControlsPanel.Title = 'Simulation Controls';
            app.SimulateButton = uibutton(app.SimulationControlsPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Tooltip = {'Run the simulation with the current parameters'};
            app.SimulateButton.Text = 'Simulate';
            app.CrossRangeStopsSliderLabel = uilabel(app.SimulationControlsPanel);
            app.CrossRangeStopsSliderLabel.Text = 'Cross-Range Stops';
            app.CrossRangeStopsSlider = uislider(app.SimulationControlsPanel);
            app.CrossRangeStopsSlider.Limits = [10 200];
            app.CrossRangeStopsSlider.Value = 10;
            app.FilterOutputCheckBox = uicheckbox(app.SimulationControlsPanel);
            app.FilterOutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterOutputCheckBoxValueChanged, true);
            app.FilterOutputCheckBox.Text = 'Filter Output';
            app.FilterOutputCheckBox.Value = true;
            app.PlotMatchedFilteringCheckBox = uicheckbox(app.SimulationControlsPanel);
            app.PlotMatchedFilteringCheckBox.Text = 'Plot Matched Filtering';
            app.PlotSignalsCheckBox = uicheckbox(app.SimulationControlsPanel);
            app.PlotSignalsCheckBox.Text = 'Plot Signals';
            app.CarrierFrequencyEditFieldLabel = uilabel(app.SimulationControlsPanel);
            app.CarrierFrequencyEditFieldLabel.Text = 'Carrier Frequency';
            app.CarrierFrequencyEditField = uieditfield(app.SimulationControlsPanel, 'numeric');
            app.CarrierUnits = uidropdown(app.SimulationControlsPanel);
            app.CarrierUnits.Items = {'Hz', 'kHz', 'MHz', 'GHz'};
            app.CarrierUnits.Value = 'Hz';
            app.PlotACFCheckBox = uicheckbox(app.SimulationControlsPanel);
            app.PlotACFCheckBox.Text = 'Plot ACF';
            app.execution = uicheckbox(app.SimulationControlsPanel);
            app.execution.Visible = 'off';
            app.execution.Text = '';
            app.SimulatingLabel = uilabel(app.SimulationControlsPanel);
            app.SimulatingLabel.Visible = 'off';
            app.SimulatingLabel.Text = 'Simulating...';
            app.FilterThresholdSliderLabel = uilabel(app.SimulationControlsPanel);
            app.FilterThresholdSliderLabel.Text = 'Filter Threshold';
            app.FilterThreshold = uislider(app.SimulationControlsPanel);
            app.FilterThreshold.ValueChangedFcn = createCallbackFcn(app, @FilterThresholdValueChanged, true);
            app.FilterThreshold.Tooltip = {'Edit the threshold value for the filtered image. Higher values result in a more filtered image. '};
            app.FilterThreshold.Value = 50;
            app.Warning = uilabel(app.SimulationControlsPanel);
            app.Warning.Visible = 'off';
            app.Warning.Text = 'Warning: This value will filter out most of the image. Data will be lost. ';
            app.WindowWidthSliderLabel = uilabel(app.SimulationControlsPanel);
            app.WindowWidthSliderLabel.Text = 'Window Width';
            app.WindowWidth = uislider(app.SimulationControlsPanel);
            app.WindowWidth.Limits = [10 512];
            app.WindowWidth.MajorTicks = [10 50 90 130 170 210 250 290 330 370 410 450 512];
            app.WindowWidth.MajorTickLabels = {'10', '50', '90', '130', '170', '210', '250', '290', '330', '370', '410', '450', 'Max'};
            app.WindowWidth.MinorTicks = [];
            app.WindowWidth.Tooltip = {'Limit the view of the radar platform to a rectangular window centered on the radar''s position. Value is in pixels.'};
            app.WindowWidth.Value = 512;
            app.FreeSpaceLoss = uicheckbox(app.SimulationControlsPanel);
            app.FreeSpaceLoss.Text = 'Free Space Loss';
            app.CarrierSignalCheckBox = uicheckbox(app.SimulationControlsPanel);
            app.CarrierSignalCheckBox.ValueChangedFcn = createCallbackFcn(app, @CarrierSignalCheckBoxValueChanged, true);
            app.CarrierSignalCheckBox.Text = 'Carrier Signal';
            app.CarrierSignalCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SigStudioFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end